classdef Drink_selection_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BittersCheckBox              matlab.ui.control.CheckBox
        SodaWaterCheckBox            matlab.ui.control.CheckBox
        PleaseselectyourmixersLabel  matlab.ui.control.Label
        BeginmakingdrinkButton       matlab.ui.control.Button
        YouhaveselectedLabel         matlab.ui.control.Label
        BSel                         matlab.ui.control.EditField
        SWSel                        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginmakingdrinkButton
        function BeginmakingdrinkButtonPushed(app, event)
            if app.BittersCheckBox.Value == 1
                app.BSel.Value = "Bitters";
            else
                app.BSel.Value = "No bitters";
            end
            
            if app.SodaWaterCheckBox.Value == 1
                app.SWSel.Value = "Soda water";
            else
                app.SWSel.Value = "No soda water";
            end
            
            app.BittersCheckBox.Enable = 'off';
            app.SodaWaterCheckBox.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BittersCheckBox = uicheckbox(app.UIFigure);
            app.BittersCheckBox.Text = 'Bitters';
            app.SodaWaterCheckBox = uicheckbox(app.UIFigure);
            app.SodaWaterCheckBox.Text = 'Soda Water';
            app.PleaseselectyourmixersLabel = uilabel(app.UIFigure);
            app.PleaseselectyourmixersLabel.Text = 'Please select your mixers';
            app.BeginmakingdrinkButton = uibutton(app.UIFigure, 'push');
            app.BeginmakingdrinkButton.ButtonPushedFcn = createCallbackFcn(app, @BeginmakingdrinkButtonPushed, true);
            app.BeginmakingdrinkButton.Text = 'Begin making drink';
            app.YouhaveselectedLabel = uilabel(app.UIFigure);
            app.YouhaveselectedLabel.Text = 'You have selected:';
            app.BSel = uieditfield(app.UIFigure, 'text');
            app.SWSel = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drink_selection_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end