classdef GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        eStop              matlab.ui.control.Button
        Resume             matlab.ui.control.Button
        PopUpMenu          matlab.ui.control.DropDown
        text8              matlab.ui.control.Label
        Joint1SliderLabel  matlab.ui.control.Label
        Joint1Slider       matlab.ui.control.Slider
        Joint2SliderLabel  matlab.ui.control.Label
        Joint2Slider       matlab.ui.control.Slider
        Joint3SliderLabel  matlab.ui.control.Label
        Joint3Slider       matlab.ui.control.Slider
        Joint4SliderLabel  matlab.ui.control.Label
        Joint4Slider       matlab.ui.control.Slider
        Joint5SliderLabel  matlab.ui.control.Label
        Joint5Slider       matlab.ui.control.Slider
        Joint6SliderLabel  matlab.ui.control.Label
        Joint6Slider       matlab.ui.control.Slider
        EditField          matlab.ui.control.NumericEditField
        EditField_2        matlab.ui.control.NumericEditField
        EditField_3        matlab.ui.control.NumericEditField
        EditField_4        matlab.ui.control.NumericEditField
        EditField_5        matlab.ui.control.NumericEditField
        EditField_6        matlab.ui.control.NumericEditField
        J1L                matlab.ui.control.Button
        J1R                matlab.ui.control.Button
        J2L                matlab.ui.control.Button
        J2R                matlab.ui.control.Button
        J3L                matlab.ui.control.Button
        J3R                matlab.ui.control.Button
        J4L                matlab.ui.control.Button
        J4R                matlab.ui.control.Button
        J5L                matlab.ui.control.Button
        J5R                matlab.ui.control.Button
        J6L                matlab.ui.control.Button
        J6R                matlab.ui.control.Button
        xPlus              matlab.ui.control.Button
        xMinus             matlab.ui.control.Button
        XDirectionLabel    matlab.ui.control.Label
        yPlus              matlab.ui.control.Button
        yMinus             matlab.ui.control.Button
        YDirectionLabel    matlab.ui.control.Label
        zPlus              matlab.ui.control.Button
        zMinus             matlab.ui.control.Button
        ZDirectionLabel    matlab.ui.control.Label
        EditFieldX         matlab.ui.control.NumericEditField
        EditFieldY         matlab.ui.control.NumericEditField
        EditFieldZ         matlab.ui.control.NumericEditField
        PopUpMenu_2        matlab.ui.control.DropDown
        text8_2            matlab.ui.control.Label
        EditFieldMotion    matlab.ui.control.EditField
        Lamp               matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to GUI (see VARARGIN)
%             
%             % Choose default command line output for GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: eStop
        function eStopButtonPushed(app, event)
            app.EditFieldMotion.Value = 'Robots stopped';
            app.Lamp.Color = [1 0 0];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Joint1Slider.Value = value;
            
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            app.EditField.Value = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Joint2Slider.Value = value;
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.EditField_3.Value = value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Joint3Slider.Value = value;
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.EditField_2.Value = value;
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            app.EditField_4.Value = value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Joint4Slider.Value = value;         
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            app.EditField_5.Value = value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.Joint5Slider.Value = value;         
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            app.EditField_6.Value = value;          
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.Joint6Slider.Value = value;
        end

        % Button pushed function: J1L
        function J1LButtonPushed(app, event)
            app.Joint1Slider.Value =  app.Joint1Slider.Value - 2;
            app.EditField.Value = app.EditField.Value - 2;
        end

        % Button pushed function: J1R
        function J1RButtonPushed(app, event)
            app.Joint1Slider.Value =  app.Joint1Slider.Value + 2;
            app.EditField.Value = app.EditField.Value + 2;
        end

        % Button pushed function: J2L
        function J2LButtonPushed(app, event)
            app.Joint2Slider.Value =  app.Joint2Slider.Value - 2;
            app.EditField_2.Value = app.EditField_2.Value - 2;
        end

        % Button pushed function: J2R
        function J2RButtonPushed(app, event)
            app.Joint2Slider.Value =  app.Joint2Slider.Value + 2;
            app.EditField_2.Value = app.EditField_2.Value + 2;
        end

        % Button pushed function: J3L
        function J3LButtonPushed(app, event)
            app.Joint3Slider.Value =  app.Joint3Slider.Value - 2;
            app.EditField_3.Value = app.EditField_3.Value - 2;
        end

        % Button pushed function: J3R
        function J3RButtonPushed(app, event)
            app.Joint3Slider.Value =  app.Joint3Slider.Value + 2;
            app.EditField_3.Value = app.EditField_3.Value + 2;
        end

        % Button pushed function: J4L
        function J4LButtonPushed(app, event)
            app.Joint4Slider.Value =  app.Joint4Slider.Value - 2;
            app.EditField_4.Value = app.EditField_4.Value - 2;
        end

        % Button pushed function: J4R
        function J4RButtonPushed(app, event)
            app.Joint4Slider.Value =  app.Joint4Slider.Value + 2;
            app.EditField_4.Value = app.EditField_4.Value + 2;
        end

        % Button pushed function: J5L
        function J5LButtonPushed(app, event)
            app.Joint5Slider.Value =  app.Joint5Slider.Value - 2;
            app.EditField_5.Value = app.EditField_5.Value - 2;
        end

        % Button pushed function: J5R
        function J5RButtonPushed(app, event)
            app.Joint5Slider.Value =  app.Joint5Slider.Value + 2;
            app.EditField_5.Value = app.EditField_5.Value + 2;
        end

        % Button pushed function: J6L
        function J6LButtonPushed(app, event)
            app.Joint6Slider.Value =  app.Joint6Slider.Value - 2;
            app.EditField_6.Value = app.EditField_6.Value - 2;
        end

        % Button pushed function: J6R
        function J6RButtonPushed(app, event)
            app.Joint6Slider.Value =  app.Joint6Slider.Value + 2;
            app.EditField_6.Value = app.EditField_6.Value + 2;
        end

        % Value changed function: PopUpMenu
        function PopUpMenuValueChanged(app, event)
            value = app.PopUpMenu.Value;
            if value == "UR3"
                app.Joint6Slider.Enable = 'on';
                app.J6L.Enable = 'on';
                app.J6R.Enable = 'on';
                app.EditField_6.Enable = 'on';
                app.Joint1Slider.Limits = [-360 360];
                app.Joint2Slider.Limits = [-360 360];
                app.Joint2Slider.MajorTicks = [-360,-270,-180,-90,0,90,180,270,360];
                app.Joint3Slider.Limits = [-360 360];
                app.Joint4Slider.Limits = [-360 360];
                app.Joint5Slider.Limits = [-360 360];
                app.Joint6Slider.Limits = [-360 360];
                app.EditField.Value = 0;
                app.Joint1Slider.Value = 0;
                app.EditField_2.Value = 0;
                app.Joint2Slider.Value = 0;
                app.EditField_3.Value = 0;
                app.Joint3Slider.Value = 0;
                app.EditField_4.Value = 0;
                app.Joint4Slider.Value = 0;
                app.EditField_5.Value = 0;
                app.Joint5Slider.Value = 0;
                app.EditField_6.Value = 0;
                app.Joint6Slider.Value = 0;

            end
            if value == "Braccio"
                app.Joint6Slider.Enable = 'off';
                app.J6L.Enable = 'off';
                app.J6R.Enable = 'off';
                app.EditField_6.Enable = 'off';
                app.Joint1Slider.Limits = [0 180];
                app.Joint2Slider.Limits = [15 165];
                app.Joint2Slider.MajorTicks = [15,90,165];
                app.Joint3Slider.Limits = [0 180];
                app.Joint4Slider.Limits = [0 180];
                app.Joint5Slider.Limits = [0 180];
                app.EditField.Value = 0;
                app.Joint1Slider.Value = 0;
                app.EditField_2.Value = 15;
                app.Joint2Slider.Value = 15;
                app.EditField_3.Value = 0;
                app.Joint3Slider.Value = 0;
                app.EditField_4.Value = 0;
                app.Joint4Slider.Value = 0;
                app.EditField_5.Value = 0;
                app.Joint5Slider.Value = 0;
                app.EditField_6.Value = 0;
                app.Joint6Slider.Value = 0;

            end
           
        end

        % Button pushed function: xPlus
        function xPlusButtonPushed(app, event)
             app.EditFieldX.Value = app.EditFieldX.Value + 0.01;
        end

        % Value changed function: EditFieldX
        function EditFieldXValueChanged(app, event)
            value = app.EditFieldX.Value;
            
        end

        % Button pushed function: xMinus
        function xMinusButtonPushed(app, event)
            app.EditFieldX.Value = app.EditFieldX.Value - 0.01;
        end

        % Button pushed function: yPlus
        function yPlusButtonPushed(app, event)
            app.EditFieldY.Value = app.EditFieldY.Value + 0.01;
        end

        % Button pushed function: yMinus
        function yMinusButtonPushed(app, event)
            app.EditFieldY.Value = app.EditFieldY.Value - 0.01;
        end

        % Button pushed function: zPlus
        function zPlusButtonPushed(app, event)
            app.EditFieldZ.Value = app.EditFieldZ.Value + 0.01;
        end

        % Button pushed function: zMinus
        function zMinusButtonPushed(app, event)
            app.EditFieldZ.Value = app.EditFieldZ.Value - 0.01;
        end

        % Value changed function: PopUpMenu_2
        function PopUpMenu_2ValueChanged(app, event)
            value = app.PopUpMenu_2.Value;
            
        end

        % Button pushed function: Resume
        function ResumeButtonPushed(app, event)
            app.EditFieldMotion.Value = 'Robots in motion';
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: EditFieldMotion
        function EditFieldMotionValueChanged(app, event)
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI';
            app.eStop = uibutton(app.figure1, 'push');
            app.eStop.ButtonPushedFcn = createCallbackFcn(app, @eStopButtonPushed, true);
            app.eStop.Text = 'eStop';
            app.Resume = uibutton(app.figure1, 'push');
            app.Resume.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.Resume.Text = 'Resume';
            app.PopUpMenu = uidropdown(app.figure1);
            app.PopUpMenu.Items = {'UR3', 'Braccio'};
            app.PopUpMenu.ValueChangedFcn = createCallbackFcn(app, @PopUpMenuValueChanged, true);
            app.PopUpMenu.Value = 'UR3';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Select Robot Arm';
            app.Joint1SliderLabel = uilabel(app.figure1);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.figure1);
            app.Joint1Slider.Limits = [-360 360];
            app.Joint1Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint2SliderLabel = uilabel(app.figure1);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.figure1);
            app.Joint2Slider.Limits = [-360 360];
            app.Joint2Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint3SliderLabel = uilabel(app.figure1);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.figure1);
            app.Joint3Slider.Limits = [-360 360];
            app.Joint3Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.MinorTicks = [];
            app.Joint4SliderLabel = uilabel(app.figure1);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.figure1);
            app.Joint4Slider.Limits = [-360 360];
            app.Joint4Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint5SliderLabel = uilabel(app.figure1);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.figure1);
            app.Joint5Slider.Limits = [-360 360];
            app.Joint5Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.MinorTicks = [];
            app.Joint6SliderLabel = uilabel(app.figure1);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.figure1);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.MinorTicks = [];
            app.EditField = uieditfield(app.figure1, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.figure1, 'numeric');
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.figure1, 'numeric');
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.figure1, 'numeric');
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.figure1, 'numeric');
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.figure1, 'numeric');
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.J1L = uibutton(app.figure1, 'push');
            app.J1L.ButtonPushedFcn = createCallbackFcn(app, @J1LButtonPushed, true);
            app.J1L.Text = '<';
            app.J1R = uibutton(app.figure1, 'push');
            app.J1R.ButtonPushedFcn = createCallbackFcn(app, @J1RButtonPushed, true);
            app.J1R.Text = '>';
            app.J2L = uibutton(app.figure1, 'push');
            app.J2L.ButtonPushedFcn = createCallbackFcn(app, @J2LButtonPushed, true);
            app.J2L.Text = '<';
            app.J2R = uibutton(app.figure1, 'push');
            app.J2R.ButtonPushedFcn = createCallbackFcn(app, @J2RButtonPushed, true);
            app.J2R.Text = '>';
            app.J3L = uibutton(app.figure1, 'push');
            app.J3L.ButtonPushedFcn = createCallbackFcn(app, @J3LButtonPushed, true);
            app.J3L.Text = '<';
            app.J3R = uibutton(app.figure1, 'push');
            app.J3R.ButtonPushedFcn = createCallbackFcn(app, @J3RButtonPushed, true);
            app.J3R.Text = '>';
            app.J4L = uibutton(app.figure1, 'push');
            app.J4L.ButtonPushedFcn = createCallbackFcn(app, @J4LButtonPushed, true);
            app.J4L.Text = '<';
            app.J4R = uibutton(app.figure1, 'push');
            app.J4R.ButtonPushedFcn = createCallbackFcn(app, @J4RButtonPushed, true);
            app.J4R.Text = '>';
            app.J5L = uibutton(app.figure1, 'push');
            app.J5L.ButtonPushedFcn = createCallbackFcn(app, @J5LButtonPushed, true);
            app.J5L.Text = '<';
            app.J5R = uibutton(app.figure1, 'push');
            app.J5R.ButtonPushedFcn = createCallbackFcn(app, @J5RButtonPushed, true);
            app.J5R.Text = '>';
            app.J6L = uibutton(app.figure1, 'push');
            app.J6L.ButtonPushedFcn = createCallbackFcn(app, @J6LButtonPushed, true);
            app.J6L.Text = '<';
            app.J6R = uibutton(app.figure1, 'push');
            app.J6R.ButtonPushedFcn = createCallbackFcn(app, @J6RButtonPushed, true);
            app.J6R.Text = '>';
            app.xPlus = uibutton(app.figure1, 'push');
            app.xPlus.ButtonPushedFcn = createCallbackFcn(app, @xPlusButtonPushed, true);
            app.xPlus.Text = '+';
            app.xMinus = uibutton(app.figure1, 'push');
            app.xMinus.ButtonPushedFcn = createCallbackFcn(app, @xMinusButtonPushed, true);
            app.xMinus.Text = '-';
            app.XDirectionLabel = uilabel(app.figure1);
            app.XDirectionLabel.Text = 'X Direction';
            app.yPlus = uibutton(app.figure1, 'push');
            app.yPlus.ButtonPushedFcn = createCallbackFcn(app, @yPlusButtonPushed, true);
            app.yPlus.Text = '+';
            app.yMinus = uibutton(app.figure1, 'push');
            app.yMinus.ButtonPushedFcn = createCallbackFcn(app, @yMinusButtonPushed, true);
            app.yMinus.Text = '-';
            app.YDirectionLabel = uilabel(app.figure1);
            app.YDirectionLabel.Text = 'Y Direction';
            app.zPlus = uibutton(app.figure1, 'push');
            app.zPlus.ButtonPushedFcn = createCallbackFcn(app, @zPlusButtonPushed, true);
            app.zPlus.Text = '+';
            app.zMinus = uibutton(app.figure1, 'push');
            app.zMinus.ButtonPushedFcn = createCallbackFcn(app, @zMinusButtonPushed, true);
            app.zMinus.Text = '-';
            app.ZDirectionLabel = uilabel(app.figure1);
            app.ZDirectionLabel.Text = 'Z Direction';
            app.EditFieldX = uieditfield(app.figure1, 'numeric');
            app.EditFieldX.ValueChangedFcn = createCallbackFcn(app, @EditFieldXValueChanged, true);
            app.EditFieldY = uieditfield(app.figure1, 'numeric');
            app.EditFieldZ = uieditfield(app.figure1, 'numeric');
            app.PopUpMenu_2 = uidropdown(app.figure1);
            app.PopUpMenu_2.Items = {'Sliders', 'X, Y and Z Directions'};
            app.PopUpMenu_2.ValueChangedFcn = createCallbackFcn(app, @PopUpMenu_2ValueChanged, true);
            app.PopUpMenu_2.Value = 'Sliders';
            app.text8_2 = uilabel(app.figure1);
            app.text8_2.Text = 'Select Control Method';
            app.EditFieldMotion = uieditfield(app.figure1, 'text');
            app.EditFieldMotion.ValueChangedFcn = createCallbackFcn(app, @EditFieldMotionValueChanged, true);
            app.EditFieldMotion.Value = 'Robots in motion';
            app.Lamp = uilamp(app.figure1);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end